classdef SoundAnnotatorMain2016 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel_5                   matlab.ui.container.Panel
        FileSelector1             matlab.ui.control.Button
        Audio1namedisplay         matlab.ui.control.EditField
        FileSelector1_2           matlab.ui.control.Button
        Audio2namedisplay         matlab.ui.control.EditField
        AudioFile1Label           matlab.ui.control.Label
        AudioFile2Label           matlab.ui.control.Label
        RecordAudio1Button        matlab.ui.control.StateButton
        RecordAudio2Button        matlab.ui.control.StateButton
        Panel_4                   matlab.ui.container.Panel
        UIAxesA1                  matlab.ui.control.UIAxes
        UIAxesA2                  matlab.ui.control.UIAxes
        PlayAudio1                matlab.ui.control.Button
        Audio1MinTextField        matlab.ui.control.NumericEditField
        Audio1MaxTextField        matlab.ui.control.NumericEditField
        ToLabel                   matlab.ui.control.Label
        DurationLabel             matlab.ui.control.Label
        PlayAudio2                matlab.ui.control.Button
        Audio2MinTextField        matlab.ui.control.NumericEditField
        Audio2MaxTextField        matlab.ui.control.NumericEditField
        ToLabel_2                 matlab.ui.control.Label
        DurationLabel_2           matlab.ui.control.Label
        AudioSize1Label           matlab.ui.control.Label
        AudioSize2Label           matlab.ui.control.Label
        UIAxesMerged              matlab.ui.control.UIAxes
        PlayAudioMerged           matlab.ui.control.Button
        AudioMergedSizeLabel      matlab.ui.control.Label
        MergeSelectionButton      matlab.ui.control.Button
        MergeTypeDropDownLabel    matlab.ui.control.Label
        MergeTypeSelect           matlab.ui.control.DropDown
        SecondaryAudioStartText   matlab.ui.control.NumericEditField
        AudioMergedSizeLabel_2    matlab.ui.control.Label
        SliderLabel               matlab.ui.control.Label
        VolumeSliderAudio1        matlab.ui.control.Slider
        VolumeSliderAudio2        matlab.ui.control.Slider
        Panel_3                   matlab.ui.container.Panel
        AudioDeviceDropDownLabel  matlab.ui.control.Label
        AudioDeviceDropDown       matlab.ui.control.DropDown
        Panel_6                   matlab.ui.container.Panel
        PlayButton                matlab.ui.control.Button
        PauseButton               matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        VolumeSliderLabel         matlab.ui.control.Label
        VolumeSlider              matlab.ui.control.Slider
    end


    properties (Access = private)
        audio1 % First selected audio
        audio2 % Second selected audio
        audioMerged = 0;% Merged Audio
        audioPlayer1 % audio player for first audio
        audioPlayer2 % audio player for second audio
        audioPlayerMerged = 0 % audio player for merged audio
        mergedY % merged audio y for saving
        mergedFS % merged audio FS for saving
       
    end

    methods (Access = private)
    
        
        function results = setupAudio1(app)
            [y, FS] = audioread(app.audio1);
            app.audioPlayer1=audioplayer(y, FS);
            dt = 1/FS;
            t = 0:dt:(length(y)*dt)-dt;
            audioLength1=t(length(t));
            plot(app.UIAxesA1,t, y);
            app.AudioSize1Label.Text=strcat("Audio Duration: ", strcat(num2str(audioLength1), ' s.'));
            app.Audio1MinTextField.Limits=[0,audioLength1-0.01];
            app.Audio1MaxTextField.Limits=[0.1,audioLength1];
            app.Audio1MaxTextField.Value=audioLength1;
        end
    
        function results = setupAudio2(app)
            [y, FS] = audioread(app.audio2);
            app.audioPlayer2=audioplayer(y, FS);
            dt = 1/FS;
            t = 0:dt:(length(y)*dt)-dt;
            audioLength2=t(length(t));
            plot(app.UIAxesA2,t, y);
            app.AudioSize2Label.Text=strcat("Audio Duration: ", strcat(num2str(audioLength2), ' s.'));
            app.Audio2MinTextField.Limits=[0,audioLength2-0.01];
            app.Audio2MaxTextField.Limits=[0.1,audioLength2];
            app.Audio2MaxTextField.Value=audioLength2;
        end

        function output = checkAudio1Exists(app)
            if(app.audio1==0)
                output = "No";
            else
                output="Yes";
            end
        end
    
        function output = checkAudio2Exists(app)
            if(app.audio2==0)
                output = "No";
            else
                output="Yes";
            end
        end
        
        
        function result = generateRandomRecordingName(app)
            recordText = 'recording-';
            recordText = strcat(recordText, date);
            recordText = strcat(recordText, '-');
            % generate random file name
            recordText = strcat(recordText, num2str(randi(1000000000)));
            recordText = strcat(recordText, '.wav');
            result = recordText;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global audiostate;
            app.audio1=0;
            app.audio2=0;
            audiostate="";
            deviceWriter = audioDeviceWriter;
            devices = getAudioDevices(deviceWriter);
            app.AudioDeviceDropDown.Items=devices;
            
        end

        % Button pushed function: FileSelector1
        function FileSelector1ButtonPushed(app, event)
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            [filename filepath] = uigetfile({'*.mp3; *.wav; *.ogg; *.aiff'},'Audio File 1');
            %https://uk.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            figure(app.UIFigure) %to bring back focus to the app
            if(isa(filename,'double')==1)
                return;
            end
            app.audio1 = strcat(filepath, filename);
            app.Audio1namedisplay.Value=filename;
            setupAudio1(app);
        end

        % Button pushed function: FileSelector1_2
        function FileSelector1_2ButtonPushed(app, event)
            [filename filepath] = uigetfile({'*.mp3; *.wav; *.ogg; *.aiff'},'Audio File 2');
            figure(app.UIFigure) %to bring back focus to the app
            if(isa(filename,'double')==1)
                return;
            end
            app.audio2 = strcat(filepath, filename);
            app.Audio2namedisplay.Value=filename;
            setupAudio2(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            global audiostate;
            if(app.audioMerged~=0) 
                if(audiostate=="Playing")
                    resume(app.audioPlayerMerged);
                else
                    play(app.audioPlayerMerged); 
                end
                audiostate = "Playing";
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if(checkAudio1Exists(app)~="No") 
                pause(app.audioPlayer1); 
            end
            if(checkAudio2Exists(app)~="No") 
                pause(app.audioPlayer2); 
            end
            if(app.audioMerged~=0)
                pause(app.audioPlayerMerged);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            StopButtonPushed(app, event);
            delete(app) 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global audiostate;
            audiostate = "Stopped";
            if(checkAudio1Exists(app)~="No") stop(app.audioPlayer1); end
            if(checkAudio2Exists(app)~="No") stop(app.audioPlayer2); end
            if(app.audioMerged~=0) stop(app.audioPlayerMerged); end
        end

        % Button pushed function: PlayAudio1
        function PlayAudio1Pushed(app, event)
            if(app.audio1==0)
                return;
            end
            
            [y, FS] = audioread(app.audio1);
            % https://www.mathworks.com/matlabcentral/answers/377742-how-do-i-extract-a-few-seconds-from-a-given-audio-file
            [m,n]=size(y);
            dt=1/FS;
            t=dt*(0:m-1);
            idy = (t>=app.Audio1MinTextField.Value) & (t<app.Audio1MaxTextField.Value);
            y = y(idy);
            y=y*app.VolumeSliderAudio1.Value;
            app.audioPlayer1=audioplayer(y, FS);
            % https://www.mathworks.com/matlabcentral/answers/22112-how-to-plot-wav-file
            t = 0:dt:(length(y)*dt)-dt;
            plot(app.UIAxesA1,t, y);
            
            play(app.audioPlayer1);
        end

        % Value changed function: Audio1MaxTextField
        function Audio1MaxTextFieldValueChanged(app, event)
            value = app.Audio1MaxTextField.Value;
            app.Audio1MinTextField.Limits= [0, value-0.01];
            
        end

        % Button pushed function: PlayAudio2
        function PlayAudio2ButtonPushed(app, event)
            if(app.audio2==0)
                return;
            end

            [y, FS] = audioread(app.audio2);
            
            [m,n]=size(y);
            dt=1/FS;
            t=dt*(0:m-1);
            idy = (t>=app.Audio2MinTextField.Value) & (t<app.Audio2MaxTextField.Value);
            y = y(idy);
            y=y*app.VolumeSliderAudio2.Value;
            app.audioPlayer2=audioplayer(y, FS);
            
            t = 0:dt:(length(y)*dt)-dt;
            plot(app.UIAxesA2,t, y);
            
            play(app.audioPlayer2);
        end

        % Value changed function: Audio2MaxTextField
        function Audio2MaxTextFieldValueChanged(app, event)
            value = app.Audio2MaxTextField.Value;
            app.Audio2MinTextField.Limits= [0, value-0.01];
        end

        % Button pushed function: PlayAudioMerged
        function PlayAudioMergedButtonPushed(app, event)
            if(app.audioMerged==0)
                return;
            end
            play(app.audioPlayerMerged);
        end

        % Button pushed function: MergeSelectionButton
        function MergeSelectionButtonPushed(app, event)
            if(isa(app.audio2,'double')==1||isa(app.audio1,'double')==1)
                return;
            end
            
            [y1, FS] = audioread(app.audio1);
            [m,n]=size(y1);
            f1=FS;
            dt=1/FS;
            t=dt*(0:m-1);
            idy1 = (t>=app.Audio1MinTextField.Value) & (t<app.Audio1MaxTextField.Value);
            y1 = y1(idy1);
            y1=y1*app.VolumeSliderAudio1.Value;
            
            [y2, FS] = audioread(app.audio2);
            [m,n]=size(y2);
            f2=FS;
            dt=1/FS;
            t=dt*(0:m-1);
            idy2 = (t>=app.Audio2MinTextField.Value) & (t<app.Audio2MaxTextField.Value);
            y2 = y2(idy2);
            y2=y2*app.VolumeSliderAudio2.Value;

            y1=resample(y1, f2, f1);
       
            if(size(y1,1)>1)
                y1=y1.';
            end
            if (size(y2,1)>1)
                y2=y2.';
            end
            
            dt=1/f2;
            t1 = 0:dt:(length(y1)*dt)-dt;
            audioLength1=t1(length(t1));
            t2 = 0:dt:(length(y2)*dt)-dt;
            audioLength2=t2(length(t2));
                    
            switch app.MergeTypeSelect.Value
                case "Concat"
                    y = [y1(:); y2(:)];
                    
                case "Truncate Larger Audio"
                    len=min(length(y1),length(y2));       
                    y=y1(1:len) + y2(1:len);
                    
                case "Audio 1 Primary"
                    % https://www.mathworks.com/matlabcentral/answers/312690-how-to-add-zeros-on-the-end-of-a-signal
                    
                    val=app.SecondaryAudioStartText.Value;
                    app.SecondaryAudioStartText.Limits=[0,audioLength1+10];
                                        
                    zb = zeros(floor(f2*val),1);
                    if((audioLength2+val)>audioLength1)
                        zpa = zeros(floor((audioLength2+val)-audioLength1)*f2,1);
                        za=zeros(0,1);
                    else
                        za = zeros(floor(audioLength1-(audioLength2+val))*f2,1);
                        zpa = zeros(0,1);
                    end
                    
                    y2 = [zb;y2(:);za;];
                    y1 = [y1(:); zpa;];
                    
                    len=min(length(y1),length(y2));       
                    y=y1(1:len) + y2(1:len);
                    
                case "Audio 2 Primary"
                    
                    val=app.SecondaryAudioStartText.Value;
                    app.SecondaryAudioStartText.Limits=[0,audioLength2+10];
                    
                    zb = zeros(floor(f2*val),1);
                    if((audioLength1+val)>audioLength2)
                        zpa = zeros(floor((audioLength1+val)-audioLength2)*f2,1);
                        za=zeros(0,1);
                    else
                        za = zeros(floor(audioLength2-(audioLength1+val))*f2,1);
                        zpa = zeros(0,1);
                    end
                        
                    y1 = [zb;y1(:);za;];
                    y2 = [y2(:); zpa;];
                    
                    len=min(length(y1),length(y2));       
                    y=y1(1:len) + y2(1:len);
                    
                    
                otherwise
                    y = y1;
            end
            
            % Assign relevant volume according to slider
            % https://stackoverflow.com/questions/32982549/volume-slider-in-matlab-gui
            app.audioPlayerMerged=audioplayer(y*app.VolumeSlider.Value,FS);
            
            t = 0:dt:(length(y)*dt)-dt;
            audioLength = t(length(t));
            app.AudioMergedSizeLabel.Text=strcat("Audio Duration: ", strcat(num2str(audioLength), ' s.'));
            plot(app.UIAxesMerged,t, y);
            app.audioMerged = 1;
            app.mergedY = y*app.VolumeSlider.Value;
            app.mergedFS = FS;
        end

        % Value changed function: MergeTypeSelect
        function MergeTypeSelectValueChanged(app, event)
            value = app.MergeTypeSelect.Value;
            switch value
                case "Concat"
                    app.SecondaryAudioStartText.Enable=0;
                    
                case "Truncate Larger Audio"
                    app.SecondaryAudioStartText.Enable=0;

                case "Audio 1 Primary"
                    app.SecondaryAudioStartText.Enable=1;
                   
                case "Audio 2 Primary"
                    app.SecondaryAudioStartText.Enable=1;
                   
                otherwise
                    app.SecondaryAudioStartText.Enable=0;

            end

        end

        % Value changed function: RecordAudio1Button
        function RecordAudio1ButtonValueChanged(app, event)
            value = app.RecordAudio1Button.Value;
            global recorder;
            if(value==1) % When record clicked
                app.RecordAudio2Button.Enable=0;
                app.RecordAudio1Button.Text="Stop";
                app.RecordAudio1Button.Icon = 'muted.png';
                recorder = audiorecorder;
                record(recorder);
            else % When stop clicked
                app.RecordAudio2Button.Enable=1;
                app.RecordAudio1Button.Text="Record";
                app.RecordAudio1Button.Icon = 'microphone.png';
                stop(recorder);
                recordingFileName = generateRandomRecordingName(app);
                recordingName = strcat('recordings/', recordingFileName);
                audioFile = getaudiodata(recorder)
                audiowrite(recordingName,audioFile, 8000);
                
                app.audio1 = recordingName;
                app.Audio1namedisplay.Value=recordingFileName;
                setupAudio1(app);
            end

        end

        % Value changed function: RecordAudio2Button
        function RecordAudio2ButtonValueChanged(app, event)
            value = app.RecordAudio2Button.Value;
            global recorder;
            if(value==1) % When record clicked
                app.RecordAudio1Button.Enable=0;
                app.RecordAudio2Button.Text="Stop";
                app.RecordAudio2Button.Icon = 'muted.png';
                recorder = audiorecorder;
                record(recorder);
            else
                app.RecordAudio1Button.Enable=1;
                app.RecordAudio2Button.Text="Record";
                app.RecordAudio2Button.Icon = 'microphone.png';
                
                stop(recorder);
                recordingFileName = generateRandomRecordingName(app);
                recordingName = strcat('recordings/', recordingFileName);
                audioFile = getaudiodata(recorder);
                audiowrite(recordingName,audioFile, 8000);
                
                app.audio2 = recordingName;
                app.Audio2namedisplay.Value=recordingFileName;
                setupAudio2(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if(app.audioMerged==0)
                return;
            end
            % https://www.mathworks.com/help/matlab/ref/uiputfile.html
            [nfname,npath]=uiputfile('.wav','Save sound','output.wav');
            figure(app.UIFigure) %to bring back focus to the app
            if isequal(nfname,0) || isequal(npath,0)
               return   % or whatever other action if 'CANCEL'
            else
               nwavfile=fullfile(npath, nfname);
               audiowrite(nwavfile,app.mergedY,app.mergedFS);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Sound Annotator';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_5 = uipanel(app.UIFigure);
            app.FileSelector1 = uibutton(app.Panel_5, 'push');
            app.FileSelector1.ButtonPushedFcn = createCallbackFcn(app, @FileSelector1ButtonPushed, true);
            app.FileSelector1.Text = 'Choose File';
            app.Audio1namedisplay = uieditfield(app.Panel_5, 'text');
            app.Audio1namedisplay.Editable = 'off';
            app.FileSelector1_2 = uibutton(app.Panel_5, 'push');
            app.FileSelector1_2.ButtonPushedFcn = createCallbackFcn(app, @FileSelector1_2ButtonPushed, true);
            app.FileSelector1_2.Text = 'Choose File';
            app.Audio2namedisplay = uieditfield(app.Panel_5, 'text');
            app.Audio2namedisplay.Editable = 'off';
            app.AudioFile1Label = uilabel(app.Panel_5);
            app.AudioFile1Label.Text = 'Audio File 1';
            app.AudioFile2Label = uilabel(app.Panel_5);
            app.AudioFile2Label.Text = 'Audio File 2';
            app.RecordAudio1Button = uibutton(app.Panel_5, 'state');
            app.RecordAudio1Button.ValueChangedFcn = createCallbackFcn(app, @RecordAudio1ButtonValueChanged, true);
            app.RecordAudio1Button.Icon = 'microphone.png';
            app.RecordAudio1Button.Text = 'Record';
            app.RecordAudio2Button = uibutton(app.Panel_5, 'state');
            app.RecordAudio2Button.ValueChangedFcn = createCallbackFcn(app, @RecordAudio2ButtonValueChanged, true);
            app.RecordAudio2Button.Icon = 'microphone.png';
            app.RecordAudio2Button.Text = 'Record';
            app.Panel_4 = uipanel(app.UIFigure);
            app.UIAxesA1 = uiaxes(app.Panel_4);
            title(app.UIAxesA1, 'Audio 1')
            app.UIAxesA2 = uiaxes(app.Panel_4);
            title(app.UIAxesA2, 'Audio 2')
            app.PlayAudio1 = uibutton(app.Panel_4, 'push');
            app.PlayAudio1.ButtonPushedFcn = createCallbackFcn(app, @PlayAudio1Pushed, true);
            app.PlayAudio1.Text = 'Play Selection';
            app.Audio1MinTextField = uieditfield(app.Panel_4, 'numeric');
            app.Audio1MinTextField.Limits = [0 1];
            app.Audio1MaxTextField = uieditfield(app.Panel_4, 'numeric');
            app.Audio1MaxTextField.Limits = [0.1 1];
            app.Audio1MaxTextField.ValueChangedFcn = createCallbackFcn(app, @Audio1MaxTextFieldValueChanged, true);
            app.Audio1MaxTextField.Value = 1;
            app.ToLabel = uilabel(app.Panel_4);
            app.ToLabel.Text = 'To';
            app.DurationLabel = uilabel(app.Panel_4);
            app.DurationLabel.Text = 'Duration:';
            app.PlayAudio2 = uibutton(app.Panel_4, 'push');
            app.PlayAudio2.ButtonPushedFcn = createCallbackFcn(app, @PlayAudio2ButtonPushed, true);
            app.PlayAudio2.Text = 'Play Selection';
            app.Audio2MinTextField = uieditfield(app.Panel_4, 'numeric');
            app.Audio2MinTextField.Limits = [0 1];
            app.Audio2MaxTextField = uieditfield(app.Panel_4, 'numeric');
            app.Audio2MaxTextField.Limits = [0.1 1];
            app.Audio2MaxTextField.ValueChangedFcn = createCallbackFcn(app, @Audio2MaxTextFieldValueChanged, true);
            app.Audio2MaxTextField.Value = 1;
            app.ToLabel_2 = uilabel(app.Panel_4);
            app.ToLabel_2.Text = 'To';
            app.DurationLabel_2 = uilabel(app.Panel_4);
            app.DurationLabel_2.Text = 'Duration:';
            app.AudioSize1Label = uilabel(app.Panel_4);
            app.AudioSize1Label.Text = 'Audio Duration: 0s';
            app.AudioSize2Label = uilabel(app.Panel_4);
            app.AudioSize2Label.Text = 'Audio Duration: 0s';
            app.UIAxesMerged = uiaxes(app.Panel_4);
            title(app.UIAxesMerged, 'Merged Audio')
            app.PlayAudioMerged = uibutton(app.Panel_4, 'push');
            app.PlayAudioMerged.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioMergedButtonPushed, true);
            app.PlayAudioMerged.Text = 'Play Selection';
            app.AudioMergedSizeLabel = uilabel(app.Panel_4);
            app.AudioMergedSizeLabel.Text = 'Audio Duration: 0s';
            app.MergeSelectionButton = uibutton(app.Panel_4, 'push');
            app.MergeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @MergeSelectionButtonPushed, true);
            app.MergeSelectionButton.Text = 'Merge Selection';
            app.MergeTypeDropDownLabel = uilabel(app.Panel_4);
            app.MergeTypeDropDownLabel.Text = 'Merge Type';
            app.MergeTypeSelect = uidropdown(app.Panel_4);
            app.MergeTypeSelect.Items = {'Concat', 'Truncate Larger Audio', 'Audio 1 Primary', 'Audio 2 Primary'};
            app.MergeTypeSelect.ValueChangedFcn = createCallbackFcn(app, @MergeTypeSelectValueChanged, true);
            app.MergeTypeSelect.Value = 'Concat';
            app.SecondaryAudioStartText = uieditfield(app.Panel_4, 'numeric');
            app.SecondaryAudioStartText.Limits = [0 Inf];
            app.SecondaryAudioStartText.Enable = 'off';
            app.AudioMergedSizeLabel_2 = uilabel(app.Panel_4);
            app.AudioMergedSizeLabel_2.Text = 'Secondary Audio Start:';
            app.SliderLabel = uilabel(app.Panel_4);
            app.SliderLabel.Text = '';
            app.VolumeSliderAudio1 = uislider(app.Panel_4);
            app.VolumeSliderAudio1.Limits = [0.01 1.99];
            app.VolumeSliderAudio1.MajorTicks = [];
            app.VolumeSliderAudio1.MajorTickLabels = {};
            app.VolumeSliderAudio1.MinorTicks = [];
            app.VolumeSliderAudio1.Value = 1;
            app.VolumeSliderAudio2 = uislider(app.Panel_4);
            app.VolumeSliderAudio2.Limits = [0.01 1.99];
            app.VolumeSliderAudio2.MajorTicks = [];
            app.VolumeSliderAudio2.MajorTickLabels = {};
            app.VolumeSliderAudio2.MinorTicks = [];
            app.VolumeSliderAudio2.Value = 1;
            app.Panel_3 = uipanel(app.UIFigure);
            app.AudioDeviceDropDownLabel = uilabel(app.Panel_3);
            app.AudioDeviceDropDownLabel.Text = 'Audio Device';
            app.AudioDeviceDropDown = uidropdown(app.Panel_3);
            app.AudioDeviceDropDown.Items = {};
            app.AudioDeviceDropDown.Value = {};
            app.Panel_6 = uipanel(app.UIFigure);
            app.PlayButton = uibutton(app.Panel_6, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = 'play.png';
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.Panel_6, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = 'pause.png';
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.Panel_6, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'stop.png';
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.Panel_6, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save.png';
            app.SaveButton.Text = 'Save';
            app.VolumeSliderLabel = uilabel(app.Panel_6);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.Panel_6);
            app.VolumeSlider.Limits = [0.01 1.99];
            app.VolumeSlider.MajorTicks = [];
            app.VolumeSlider.MajorTickLabels = {};
            app.VolumeSlider.MinorTicks = [];
            app.VolumeSlider.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = SoundAnnotatorMain2016

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end